classdef Interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Toolbar                 matlab.ui.container.Toolbar
        PushTool                matlab.ui.container.toolbar.PushTool
        TivaBBLabel             matlab.ui.control.Label
        ConectarButton          matlab.ui.control.Button
        IntensidadKnob          matlab.ui.control.Knob
        IntensidadKnobLabel     matlab.ui.control.Label
        BaudRateEditField       matlab.ui.control.EditField
        BaudRateEditFieldLabel  matlab.ui.control.Label
        PuertoEditField         matlab.ui.control.EditField
        PuertoEditFieldLabel    matlab.ui.control.Label
        HenryPrctica5Label      matlab.ui.control.Label
        RGBButtonGroup          matlab.ui.container.ButtonGroup
        BlueButton              matlab.ui.control.ToggleButton
        GreenButton             matlab.ui.control.ToggleButton
        RedButton               matlab.ui.control.ToggleButton
    end

    
    properties (Access = public)
        port
        baud_rate% Description
        serial_connection
        led_selected
        intensity_value
    end
    
    methods (Access = private)
        function conexion_serial(app)
           app.serial_connection = serialport(app.port, str2double(app.baud_rate));
        end
        function send_data(app)
           writeline(app.serial_connection, app.led_selected);
           writeline(app.serial_connection, app.intensity_value);
        end
        function desconexion_serial(app)
           delete(app.serial_connection);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PuertoEditField
        function PuertoEditFieldValueChanged(app, event)
            app.port = app.PuertoEditField.Value;
        end

        % Value changed function: BaudRateEditField
        function BaudRateEditFieldValueChanged(app, event)
            app.baud_rate = app.BaudRateEditField.Value;
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            if strcmp(app.ConectarButton.Text, "Conectar")
                app.ConectarButton.Text = "Desconectar";
                app.ConectarButton.BackgroundColor = [1 0 0];
                conexion_serial(app);
                app.IntensidadKnobLabel.Enable = 'on';
                app.IntensidadKnob.Enable = 'on';
                app.RGBButtonGroup.Enable = 'on';
            else
                app.ConectarButton.Text = "Conectar";
                app.ConectarButton.BackgroundColor = [0.3922 0.8314 0.0745];
                desconexion_serial(app);
                app.IntensidadKnobLabel.Enable = 'off';
                app.IntensidadKnob.Enable = 'off';
                app.RGBButtonGroup.Enable = 'off';
            end
        end

        % Selection changed function: RGBButtonGroup
        function RGBButtonGroupSelectionChanged(app, event)
            app.led_selected = string(app.RGBButtonGroup.SelectedObject.Text);
        end

        % Value changed function: IntensidadKnob
        function IntensidadKnobValueChanged(app, event)
            app.intensity_value = string(uint32(app.IntensidadKnob.Value));
            send_data(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.RGBButtonGroup = uibuttongroup(app.UIFigure);
            app.RGBButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RGBButtonGroupSelectionChanged, true);
            app.RGBButtonGroup.Enable = 'off';
            app.RGBButtonGroup.Title = 'RGB';
            app.RedButton = uitogglebutton(app.RGBButtonGroup);
            app.RedButton.Text = 'Red';
            app.RedButton.Value = true;
            app.GreenButton = uitogglebutton(app.RGBButtonGroup);
            app.GreenButton.Text = 'Green';
            app.BlueButton = uitogglebutton(app.RGBButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.HenryPrctica5Label = uilabel(app.UIFigure);
            app.HenryPrctica5Label.Text = 'Henry Práctica 5 ';
            app.PuertoEditFieldLabel = uilabel(app.UIFigure);
            app.PuertoEditFieldLabel.Text = 'Puerto';
            app.PuertoEditField = uieditfield(app.UIFigure, 'text');
            app.PuertoEditField.ValueChangedFcn = createCallbackFcn(app, @PuertoEditFieldValueChanged, true);
            app.BaudRateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRateEditField = uieditfield(app.UIFigure, 'text');
            app.BaudRateEditField.ValueChangedFcn = createCallbackFcn(app, @BaudRateEditFieldValueChanged, true);
            app.IntensidadKnobLabel = uilabel(app.UIFigure);
            app.IntensidadKnobLabel.Enable = 'off';
            app.IntensidadKnobLabel.Text = 'Intensidad';
            app.IntensidadKnob = uiknob(app.UIFigure, 'continuous');
            app.IntensidadKnob.ValueChangedFcn = createCallbackFcn(app, @IntensidadKnobValueChanged, true);
            app.IntensidadKnob.Enable = 'off';
            app.ConectarButton = uibutton(app.UIFigure, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.TivaBBLabel = uilabel(app.UIFigure);
            app.TivaBBLabel.Text = 'Tiva BB';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end